classdef Filter_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TriangularButton              matlab.ui.control.Button
        SquareButton                  matlab.ui.control.Button
        SineButton                    matlab.ui.control.Button
        Wc2Slider                     matlab.ui.control.Slider
        Wc2SliderLabel                matlab.ui.control.Label
        Wc1Slider                     matlab.ui.control.Slider
        Wc1SliderLabel                matlab.ui.control.Label
        noiseratioEditField           matlab.ui.control.NumericEditField
        noiseratioEditFieldLabel      matlab.ui.control.Label
        ButterworthFilterLabel        matlab.ui.control.Label
        AddNoiseSlider                matlab.ui.control.Slider
        AddNoiseLabel                 matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        PhaseEditField                matlab.ui.control.NumericEditField
        PhaseEditFieldLabel           matlab.ui.control.Label
        InputfrequnecyEditField       matlab.ui.control.NumericEditField
        InputfrequnecyEditFieldLabel  matlab.ui.control.Label
        OrdernumbernEditField         matlab.ui.control.NumericEditField
        OrdernumbernEditFieldLabel    matlab.ui.control.Label
        CuttofffrequencyFcEditField   matlab.ui.control.NumericEditField
        CuttofffrequencyFcEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencyFsEditField  matlab.ui.control.NumericEditField
        SamplingfrequencyFsEditFieldLabel  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        lowpassMenu                   matlab.ui.container.Menu
        highpassMenu                  matlab.ui.container.Menu
        BandpassMenu                  matlab.ui.container.Menu
        BandstopMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        c,d % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SineButton
        function SineButtonPushed(app, event)
             %selectedButton = app.ButtonGroup.SelectedObject;
          %  fc = app.CuttofffrequencyFcEditField.Value;
            Fs= app.SamplingfrequencyFsEditField.Value;
           % n= app.OrdernumbernEditField.Value;
            f = app.InputfrequnecyEditField.Value;
            phase=app.PhaseEditField.Value;
            amp = app.AmplitudeEditField.Value;
            Ts = 1/Fs;
            no=app.AddNoiseSlider.Value;
          
t = 0:Ts:10;

%y = amp*app.x + no*rand(1,length(t));


y = amp*sin((2*3.14*f*t) + phase) + no*rand(1,length(t));


L= length(y);
NFFT = 2^nextpow2(L);
y_fft = abs(fft(y,NFFT));
freq = Fs/2*linspace(0,1,NFFT/2+1);
plot(app.UIAxes2,freq,y_fft(1:NFFT/2+1));

plot(app.UIAxes,y);
%wn=(2*pi*fc)/Fs;

%wn=[f1 f2]*2/Fs;

%[app.d,app.c]=butter(n,wn);

% Filtering
z=filter(app.d,app.c,y);
%z1=filter(b,a,z);

plot(app.UIAxes_2,z,'r');
%plot(app.UIAxes_2,z1,'b');
noise_ratio = snr(y,z);
app.noiseratioEditField.Value=noise_ratio;

        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
                        %selectedButton = app.ButtonGroup.SelectedObject;
          %  fc = app.CuttofffrequencyFcEditField.Value;
            Fs= app.SamplingfrequencyFsEditField.Value;
           % n= app.OrdernumbernEditField.Value;
            f = app.InputfrequnecyEditField.Value;
            phase=app.PhaseEditField.Value;
            amp = app.AmplitudeEditField.Value;
            Ts = 1/Fs;
            no=app.AddNoiseSlider.Value;
          
t = 0:Ts:10;

%y = amp*app.x + no*rand(1,length(t));
         

y = amp*square((2*3.14*f*t) + phase) + no*rand(1,length(t));


L= length(y);
NFFT = 2^nextpow2(L);
y_fft = abs(fft(y,NFFT));
freq = Fs/2*linspace(0,1,NFFT/2+1);
plot(app.UIAxes2,freq,y_fft(1:NFFT/2+1));

plot(app.UIAxes,y);
%wn=(2*pi*fc)/Fs;

%wn=[f1 f2]*2/Fs;

%[app.d,app.c]=butter(n,wn);

% Filtering
z=filter(app.d,app.c,y);
%z1=filter(b,a,z);

plot(app.UIAxes_2,z,'r');
%plot(app.UIAxes_2,z1,'b');
noise_ratio = snr(y,z);
app.noiseratioEditField.Value=noise_ratio;
        end

        % Button pushed function: TriangularButton
        function TriangularButtonPushed(app, event)
                         %selectedButton = app.ButtonGroup.SelectedObject;
          %  fc = app.CuttofffrequencyFcEditField.Value;
            Fs= app.SamplingfrequencyFsEditField.Value;
           % n= app.OrdernumbernEditField.Value;
            f = app.InputfrequnecyEditField.Value;
            phase=app.PhaseEditField.Value;
            amp = app.AmplitudeEditField.Value;
            Ts = 1/Fs;
            no=app.AddNoiseSlider.Value;
          
t = 0:Ts:10;

%y = amp*app.x + no*rand(1,length(t));
         
y = amp*sawtooth((2*3.14*f*t) + phase) + no*rand(1,length(t));


L= length(y);
NFFT = 2^nextpow2(L);
y_fft = abs(fft(y,NFFT));
freq = Fs/2*linspace(0,1,NFFT/2+1);
plot(app.UIAxes2,freq,y_fft(1:NFFT/2+1));

plot(app.UIAxes,y);
%wn=(2*pi*fc)/Fs;

%wn=[f1 f2]*2/Fs;

%[app.d,app.c]=butter(n,wn);

% Filtering
z=filter(app.d,app.c,y);
%z1=filter(b,a,z);

plot(app.UIAxes_2,z,'r');
%plot(app.UIAxes_2,z1,'b');
noise_ratio = snr(y,z);
app.noiseratioEditField.Value=noise_ratio;
        end

        % Menu selected function: lowpassMenu
        function lowpassMenuSelected(app, event)
            n=app.OrdernumbernEditField.Value;
            fc=app.CuttofffrequencyFcEditField.Value;
            fs=app.SamplingfrequencyFsEditField.Value;
            wn=2*pi*fc/fs;
            [app.d , app.c]= butter(n,wn,"low");  
        end

        % Menu selected function: highpassMenu
        function highpassMenuSelected(app, event)
           
            n=app.OrdernumbernEditField.Value;
            fc=app.CuttofffrequencyFcEditField.Value;
            fs=app.SamplingfrequencyFsEditField.Value;
            wn=2*pi*fc/fs;
            [app.d , app.c]= butter(n,wn,"high");
        end

        % Menu selected function: BandpassMenu
        function BandpassMenuSelected(app, event)
                      
          f1=app.Wc1Slider.Value;
          f2=app.Wc2Slider.Value;
          n=app.OrdernumbernEditField.Value;
          fs=app.SamplingfrequencyFsEditField.Value;
          wn=[f1 f2]*2/fs;
          [app.d , app.c]= butter(n,wn,'bandpass');

        end

        % Menu selected function: BandstopMenu
        function BandstopMenuSelected(app, event)
            f1=app.Wc1Slider.Value;
          f2=app.Wc2Slider.Value;
          n=app.OrdernumbernEditField.Value;
          fs=app.SamplingfrequencyFsEditField.Value;
          wn=[f1 f2]*2/fs;
          [app.d , app.c]= butter(n,wn,'stop');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal with noise')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtered Signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Amplitude Spectrum')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SamplingfrequencyFsEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencyFsEditFieldLabel.Text = 'Sampling frequency(Fs)';
            app.SamplingfrequencyFsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CuttofffrequencyFcEditFieldLabel = uilabel(app.UIFigure);
            app.CuttofffrequencyFcEditFieldLabel.Text = 'Cuttoff frequency(Fc)';
            app.CuttofffrequencyFcEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrdernumbernEditFieldLabel = uilabel(app.UIFigure);
            app.OrdernumbernEditFieldLabel.Text = 'Order number(n)';
            app.OrdernumbernEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputfrequnecyEditFieldLabel = uilabel(app.UIFigure);
            app.InputfrequnecyEditFieldLabel.Text = 'Input  frequnecy';
            app.InputfrequnecyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddNoiseLabel = uilabel(app.UIFigure);
            app.AddNoiseLabel.Text = {'Add '; 'Noise'};
            app.AddNoiseSlider = uislider(app.UIFigure);
            app.ButterworthFilterLabel = uilabel(app.UIFigure);
            app.ButterworthFilterLabel.Text = 'Butterworth Filter';
            app.noiseratioEditFieldLabel = uilabel(app.UIFigure);
            app.noiseratioEditFieldLabel.Text = 'noise ratio';
            app.noiseratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.Wc1SliderLabel = uilabel(app.UIFigure);
            app.Wc1SliderLabel.Text = 'Wc1';
            app.Wc1Slider = uislider(app.UIFigure);
            app.Wc2SliderLabel = uilabel(app.UIFigure);
            app.Wc2SliderLabel.Text = 'Wc2';
            app.Wc2Slider = uislider(app.UIFigure);
            app.SineButton = uibutton(app.UIFigure, 'push');
            app.SineButton.ButtonPushedFcn = createCallbackFcn(app, @SineButtonPushed, true);
            app.SineButton.Text = 'Sine';
            app.SquareButton = uibutton(app.UIFigure, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = 'Square';
            app.TriangularButton = uibutton(app.UIFigure, 'push');
            app.TriangularButton.ButtonPushedFcn = createCallbackFcn(app, @TriangularButtonPushed, true);
            app.TriangularButton.Text = 'Triangular';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.lowpassMenu = uimenu(app.ContextMenu);
            app.lowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @lowpassMenuSelected, true);
            app.lowpassMenu.Text = 'lowpass';
            app.highpassMenu = uimenu(app.ContextMenu);
            app.highpassMenu.MenuSelectedFcn = createCallbackFcn(app, @highpassMenuSelected, true);
            app.highpassMenu.Text = 'highpass';
            app.BandpassMenu = uimenu(app.ContextMenu);
            app.BandpassMenu.MenuSelectedFcn = createCallbackFcn(app, @BandpassMenuSelected, true);
            app.BandpassMenu.Text = 'Bandpass';
            app.BandstopMenu = uimenu(app.ContextMenu);
            app.BandstopMenu.MenuSelectedFcn = createCallbackFcn(app, @BandstopMenuSelected, true);
            app.BandstopMenu.Text = 'Bandstop';
            % Assign app.ContextMenu
            app.SineButton.ContextMenu = app.ContextMenu;
            app.SquareButton.ContextMenu = app.ContextMenu;
            app.TriangularButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filter_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end